classdef aux1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BackgroundColorDropDown       matlab.ui.control.DropDown
        BackgroundColorDropDownLabel  matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        ColorDropDownLabel            matlab.ui.control.Label
        VariationButtonGroup          matlab.ui.container.ButtonGroup
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        PowerSlider                   matlab.ui.control.Slider
        PowerSliderLabel              matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        NumberofIterationsEditField   matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isequal(app.BackgroundColorDropDown.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        if app.Button.Value
            var=1;
        else
            var=2;
        end
        if var==1
            n=str2num(app.NumberofIterationsEditField.Value);
            power=app.PowerSlider.Value;
            color=app.ColorDropDown.Value;
            mandelbulb1(n,power,color,bgcolor);
        else
            n=str2num(app.NumberofIterationsEditField.Value);
            power=app.PowerSlider.Value;
            color=app.ColorDropDown.Value;
            mandelbulb2(n,power,color,bgcolor);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'wallpaperflare.com_wallpaper.jpg');
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PowerSliderLabel = uilabel(app.UIFigure);
            app.PowerSliderLabel.Text = 'Power';
            app.PowerSlider = uislider(app.UIFigure);
            app.PowerSlider.Limits = [0 20];
            app.PowerSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.VariationButtonGroup = uibuttongroup(app.UIFigure);
            app.VariationButtonGroup.Title = 'Variation';
            app.Button = uiradiobutton(app.VariationButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.VariationButtonGroup);
            app.Button_2.Text = '2';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.ColorDropDown.Value = 'Red';
            app.BackgroundColorDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.UIFigure);
            app.BackgroundColorDropDown.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown.Value = 'Dark';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end