classdef aux11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofSidesEditField        matlab.ui.control.EditField
        NumberofSidesEditFieldLabel   matlab.ui.control.Label
        PhiEditField                  matlab.ui.control.EditField
        PhiEditFieldLabel             matlab.ui.control.Label
        PointsColorDropDown           matlab.ui.control.DropDown
        PointsColorDropDownLabel      matlab.ui.control.Label
        BackgroundColorDropDown       matlab.ui.control.DropDown
        BackgroundColorDropDownLabel  matlab.ui.control.Label
        NumberofIterationsEditField   matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        CirclesColorDropDown          matlab.ui.control.DropDown
        CirclesColorDropDownLabel     matlab.ui.control.Label
        SpeedButtonGroup              matlab.ui.container.ButtonGroup
        Button_3                      matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        AnimationCheckBox             matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isequal(app.BackgroundColorDropDown.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField.Value);
        phi=str2num(app.PhiEditField.Value);
        sides=str2num(app.NumberofSidesEditField.Value);
        color1=app.CirclesColorDropDown.Value;
        color2=app.PointsColorDropDown.Value;
        animation=app.AnimationCheckBox.Value;
        if app.Button.Value
            n=10;
            apollonian1(n,animation,bgcolor,color1,color2,sides,phi,i);
        elseif app.Button_2.Value
            n=100;
            apollonian1(n,animation,bgcolor,color1,color2,sides,phi,i);
        elseif app.Button_3.Value
            n=1000;
            apollonian1(n,animation,bgcolor,color1,color2,sides,phi,i);
        else
            n=10;
            apollonian1(n,animation,bgcolor,color1,color2,sides,phi,i);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'apolloniangasket.png');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AnimationCheckBox = uicheckbox(app.UIFigure);
            app.AnimationCheckBox.Text = 'Animation';
            app.SpeedButtonGroup = uibuttongroup(app.UIFigure);
            app.SpeedButtonGroup.Title = 'Speed';
            app.Button = uiradiobutton(app.SpeedButtonGroup);
            app.Button.Text = '×10';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_2.Text = '×100';
            app.Button_3 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_3.Text = '×1000';
            app.CirclesColorDropDownLabel = uilabel(app.UIFigure);
            app.CirclesColorDropDownLabel.Text = 'Circles Color';
            app.CirclesColorDropDown = uidropdown(app.UIFigure);
            app.CirclesColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.CirclesColorDropDown.Value = 'Red';
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.BackgroundColorDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.UIFigure);
            app.BackgroundColorDropDown.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown.Value = 'Dark';
            app.PointsColorDropDownLabel = uilabel(app.UIFigure);
            app.PointsColorDropDownLabel.Text = 'Points Color';
            app.PointsColorDropDown = uidropdown(app.UIFigure);
            app.PointsColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.PointsColorDropDown.Value = 'Red';
            app.PhiEditFieldLabel = uilabel(app.UIFigure);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofSidesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSidesEditFieldLabel.Text = 'Number of Sides';
            app.NumberofSidesEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end