classdef aux13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VariationButtonGroup          matlab.ui.container.ButtonGroup
        MengerSpongeButton            matlab.ui.control.RadioButton
        Button_6                      matlab.ui.control.RadioButton
        Button_5                      matlab.ui.control.RadioButton
        Button_4                      matlab.ui.control.RadioButton
        BackgroundColorDropDown       matlab.ui.control.DropDown
        BackgroundColorDropDownLabel  matlab.ui.control.Label
        NumberofIterationsEditField   matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        ColorDropDownLabel            matlab.ui.control.Label
        SpeedButtonGroup              matlab.ui.container.ButtonGroup
        Button_3                      matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        AnimationCheckBox             matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isequal(app.BackgroundColorDropDown.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField.Value);
        color=app.ColorDropDown.Value;
        animation=app.AnimationCheckBox.Value;
        if app.Button.Value
            n=10;
            if app.Button_4.Value
                chaos3d(n,animation,bgcolor,color,3,i);
            elseif app.Button_5.Value
                chaos3d(n,animation,bgcolor,color,4,i);
            elseif app.Button_6.Value
                chaos3d(n,animation,bgcolor,color,5,i);   
            else
                mengersponge(n,animation,bgcolor,color,i);
            end
        elseif app.Button_2.Value
            n=100;
            if app.Button_4.Value
                chaos3d(n,animation,bgcolor,color,3,i);
            elseif app.Button_5.Value
                chaos3d(n,animation,bgcolor,color,4,i);
            elseif app.Button_6.Value
                chaos3d(n,animation,bgcolor,color,5,i);   
            else
                mengersponge(n,animation,bgcolor,color,i);
            end
        elseif app.Button_3.Value
            n=1000;
            if app.Button_4.Value
                chaos3d(n,animation,bgcolor,color,3,i);
            elseif app.Button_5.Value
                chaos3d(n,animation,bgcolor,color,4,i);
            elseif app.Button_6.Value
                chaos3d(n,animation,bgcolor,color,5,i);   
            else
                mengersponge(n,animation,bgcolor,color,i);
            end
        else
            n=10;
            if app.Button_4.Value
                chaos3d(n,animation,bgcolor,color,3,i);
            elseif app.Button_5.Value
                chaos3d(n,animation,bgcolor,color,4,i);
            elseif app.Button_6.Value
                chaos3d(n,animation,bgcolor,color,5,i);   
            else
                mengersponge(n,animation,bgcolor,color,i);
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'd1f11qi-9cc627c2-051a-4df0-8ad4-71687fe7c9fb.jpg');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AnimationCheckBox = uicheckbox(app.UIFigure);
            app.AnimationCheckBox.Text = 'Animation';
            app.SpeedButtonGroup = uibuttongroup(app.UIFigure);
            app.SpeedButtonGroup.Title = 'Speed';
            app.Button = uiradiobutton(app.SpeedButtonGroup);
            app.Button.Text = '×10';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_2.Text = '×100';
            app.Button_3 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_3.Text = '×1000';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.ColorDropDown.Value = 'Red';
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.BackgroundColorDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.UIFigure);
            app.BackgroundColorDropDown.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown.Value = 'Dark';
            app.VariationButtonGroup = uibuttongroup(app.UIFigure);
            app.VariationButtonGroup.Title = 'Variation';
            app.Button_4 = uiradiobutton(app.VariationButtonGroup);
            app.Button_4.Text = '3';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.VariationButtonGroup);
            app.Button_5.Text = '4';
            app.Button_6 = uiradiobutton(app.VariationButtonGroup);
            app.Button_6.Text = '5';
            app.MengerSpongeButton = uiradiobutton(app.VariationButtonGroup);
            app.MengerSpongeButton.Text = 'Menger Sponge';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end