classdef aux14 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ChaosTab                        matlab.ui.container.Tab
        NumberofSidesEditField          matlab.ui.control.EditField
        NumberofSidesEditFieldLabel     matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        BackgroundColorDropDown         matlab.ui.control.DropDown
        BackgroundColorDropDownLabel    matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        SpeedButtonGroup                matlab.ui.container.ButtonGroup
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        AnimationCheckBox               matlab.ui.control.CheckBox
        RestrictedChaosTab              matlab.ui.container.Tab
        VariationButtonGroup            matlab.ui.container.ButtonGroup
        VicsekButton                    matlab.ui.control.RadioButton
        SierpiskiButton                 matlab.ui.control.RadioButton
        Button_10                       matlab.ui.control.RadioButton
        Button_9                        matlab.ui.control.RadioButton
        Button_8                        matlab.ui.control.RadioButton
        Button_7                        matlab.ui.control.RadioButton
        NumberofSidesEditField_2        matlab.ui.control.EditField
        NumberofSidesEditField_2Label   matlab.ui.control.Label
        BackgroundColorDropDown_2       matlab.ui.control.DropDown
        BackgroundColorDropDown_2Label  matlab.ui.control.Label
        NumberofIterationsEditField_2   matlab.ui.control.EditField
        NumberofIterationsEditField_2Label  matlab.ui.control.Label
        ColorDropDown_2                 matlab.ui.control.DropDown
        ColorDropDown_2Label            matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        SpeedButtonGroup_2              matlab.ui.container.ButtonGroup
        Button_6                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        AnimationCheckBox_2             matlab.ui.control.CheckBox
        BarnsleyFernTab                 matlab.ui.container.Tab
        BackgroundColorDropDown_3       matlab.ui.control.DropDown
        BackgroundColorDropDown_3Label  matlab.ui.control.Label
        NumberofIterationsEditField_3   matlab.ui.control.EditField
        NumberofIterationsEditField_3Label  matlab.ui.control.Label
        ColorDropDown_3                 matlab.ui.control.DropDown
        ColorDropDown_3Label            matlab.ui.control.Label
        RunButton_3                     matlab.ui.control.Button
        SpeedButtonGroup_3              matlab.ui.container.ButtonGroup
        Button_13                       matlab.ui.control.RadioButton
        Button_12                       matlab.ui.control.RadioButton
        Button_11                       matlab.ui.control.RadioButton
        AnimationCheckBox_3             matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isequal(app.BackgroundColorDropDown.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField.Value);
        sides=str2num(app.NumberofSidesEditField.Value);
        color=app.ColorDropDown.Value;
        animation=app.AnimationCheckBox.Value;
        if app.Button.Value
            n=10;
            if isequal(color,'Random')
                color='w';
                chaos(n,animation,bgcolor,color,i,sides,'c');
            else
                chaos(n,animation,bgcolor,color,i,sides,'w');
            end
        elseif app.Button_2.Value
            n=100;
            if isequal(color,'Random')
                color='w';
                chaos(n,animation,bgcolor,color,i,sides,'c');
            else
                chaos(n,animation,bgcolor,color,i,sides,'w');
            end
        elseif app.Button_3.Value
            n=1000;
            if isequal(color,'Random')
                color='w';
                chaos(n,animation,bgcolor,color,i,sides,'c');
            else
                chaos(n,animation,bgcolor,color,i,sides,'w');
            end
        else
            n=10;
            if isequal(color,'Random')
                color='w';
                chaos(n,animation,bgcolor,color,i,sides,'c');
            else
                chaos(n,animation,bgcolor,color,i,sides,'w');
            end
        end
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
        if isequal(app.BackgroundColorDropDown_2.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField_2.Value);
        sides=str2num(app.NumberofSidesEditField_2.Value);
        color=app.ColorDropDown_2.Value;
        animation=app.AnimationCheckBox_2.Value;
        if app.Button_4.Value
            n=10;
            if app.Button_7.Value
                restrictedchaos1(n,animation,bgcolor,color,i,sides);
            elseif app.Button_8.Value
                restrictedchaos2(n,animation,bgcolor,color,i,sides);
            elseif app.Button_9.Value
                restrictedchaos3(n,animation,bgcolor,color,i,sides);
            elseif app.Button_10.Value
                restrictedchaos4(n,animation,bgcolor,color,i,sides);
            elseif app.SierpiskiButton.Value
                sierpinskicarpet(n,animation,bgcolor,color,i,sides);
            else
                vicsekfractal(n,animation,bgcolor,color,i,sides);
            end
        elseif app.Button_5.Value
            n=100;
            if app.Button_7.Value
                restrictedchaos1(n,animation,bgcolor,color,i,sides);
            elseif app.Button_8.Value
                restrictedchaos2(n,animation,bgcolor,color,i,sides);
            elseif app.Button_9.Value
                restrictedchaos3(n,animation,bgcolor,color,i,sides);
            elseif app.Button_10.Value
                restrictedchaos4(n,animation,bgcolor,color,i,sides);
            elseif app.SierpiskiButton.Value
                sierpinskicarpet(n,animation,bgcolor,color,i,sides);
            else
                vicsekfractal(n,animation,bgcolor,color,i,sides);
            end
        elseif app.Button_6.Value
            n=1000;
            if app.Button_7.Value
                restrictedchaos1(n,animation,bgcolor,color,i,sides);
            elseif app.Button_8.Value
                restrictedchaos2(n,animation,bgcolor,color,i,sides);
            elseif app.Button_9.Value
                restrictedchaos3(n,animation,bgcolor,color,i,sides);
            elseif app.Button_10.Value
                restrictedchaos4(n,animation,bgcolor,color,i,sides);
            elseif app.SierpiskiButton.Value
                sierpinskicarpet(n,animation,bgcolor,color,i,sides);
            else
                vicsekfractal(n,animation,bgcolor,color,i,sides);
            end
        else
            n=10;
            if app.Button_7.Value
                restrictedchaos1(n,animation,bgcolor,color,i,sides);
            elseif app.Button_8.Value
                restrictedchaos2(n,animation,bgcolor,color,i,sides);
            elseif app.Button_9.Value
                restrictedchaos3(n,animation,bgcolor,color,i,sides);
            elseif app.Button_10.Value
                restrictedchaos4(n,animation,bgcolor,color,i,sides);
            elseif app.SierpiskiButton.Value
                sierpinskicarpet(n,animation,bgcolor,color,i,sides);
            else
                vicsekfractal(n,animation,bgcolor,color,i,sides);
            end
        end
        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)
        if isequal(app.BackgroundColorDropDown_3.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField_3.Value);
        color=app.ColorDropDown_3.Value;
        animation=app.AnimationCheckBox_3.Value;
        if app.Button_11.Value
            n=10;
            barnsleyfern(n,animation,bgcolor,color,i);
        elseif app.Button_12.Value
            n=100;
            barnsleyfern(n,animation,bgcolor,color,i);
        elseif app.Button_13.Value
            n=1000;
            barnsleyfern(n,animation,bgcolor,color,i);
        else
            n=10;
            barnsleyfern(n,animation,bgcolor,color,i);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'wallpaperflare.com_wallpaper (1).jpg');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ChaosTab = uitab(app.TabGroup);
            app.ChaosTab.Title = 'Chaos';
            app.AnimationCheckBox = uicheckbox(app.ChaosTab);
            app.AnimationCheckBox.Text = 'Animation';
            app.SpeedButtonGroup = uibuttongroup(app.ChaosTab);
            app.SpeedButtonGroup.Title = 'Speed';
            app.Button = uiradiobutton(app.SpeedButtonGroup);
            app.Button.Text = '×10';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_2.Text = '×100';
            app.Button_3 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_3.Text = '×1000';
            app.ColorDropDownLabel = uilabel(app.ChaosTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.ChaosTab);
            app.ColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta', 'Random'};
            app.ColorDropDown.Value = 'Red';
            app.NumberofIterationsEditFieldLabel = uilabel(app.ChaosTab);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.ChaosTab, 'text');
            app.BackgroundColorDropDownLabel = uilabel(app.ChaosTab);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.ChaosTab);
            app.BackgroundColorDropDown.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown.Value = 'Dark';
            app.RunButton = uibutton(app.ChaosTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NumberofSidesEditFieldLabel = uilabel(app.ChaosTab);
            app.NumberofSidesEditFieldLabel.Text = 'Number of Sides';
            app.NumberofSidesEditField = uieditfield(app.ChaosTab, 'text');
            app.RestrictedChaosTab = uitab(app.TabGroup);
            app.RestrictedChaosTab.Title = 'Restricted Chaos';
            app.AnimationCheckBox_2 = uicheckbox(app.RestrictedChaosTab);
            app.AnimationCheckBox_2.Text = 'Animation';
            app.SpeedButtonGroup_2 = uibuttongroup(app.RestrictedChaosTab);
            app.SpeedButtonGroup_2.Title = 'Speed';
            app.Button_4 = uiradiobutton(app.SpeedButtonGroup_2);
            app.Button_4.Text = '×10';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.SpeedButtonGroup_2);
            app.Button_5.Text = '×100';
            app.Button_6 = uiradiobutton(app.SpeedButtonGroup_2);
            app.Button_6.Text = '×1000';
            app.RunButton_2 = uibutton(app.RestrictedChaosTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.ColorDropDown_2Label = uilabel(app.RestrictedChaosTab);
            app.ColorDropDown_2Label.Text = 'Color';
            app.ColorDropDown_2 = uidropdown(app.RestrictedChaosTab);
            app.ColorDropDown_2.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.ColorDropDown_2.Value = 'Red';
            app.NumberofIterationsEditField_2Label = uilabel(app.RestrictedChaosTab);
            app.NumberofIterationsEditField_2Label.Text = 'Number of Iterations';
            app.NumberofIterationsEditField_2 = uieditfield(app.RestrictedChaosTab, 'text');
            app.BackgroundColorDropDown_2Label = uilabel(app.RestrictedChaosTab);
            app.BackgroundColorDropDown_2Label.Text = 'Background Color';
            app.BackgroundColorDropDown_2 = uidropdown(app.RestrictedChaosTab);
            app.BackgroundColorDropDown_2.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown_2.Value = 'Dark';
            app.NumberofSidesEditField_2Label = uilabel(app.RestrictedChaosTab);
            app.NumberofSidesEditField_2Label.Text = 'Number of Sides';
            app.NumberofSidesEditField_2 = uieditfield(app.RestrictedChaosTab, 'text');
            app.VariationButtonGroup = uibuttongroup(app.RestrictedChaosTab);
            app.VariationButtonGroup.Title = 'Variation';
            app.Button_7 = uiradiobutton(app.VariationButtonGroup);
            app.Button_7.Text = '1';
            app.Button_7.Value = true;
            app.Button_8 = uiradiobutton(app.VariationButtonGroup);
            app.Button_8.Text = '2';
            app.Button_9 = uiradiobutton(app.VariationButtonGroup);
            app.Button_9.Text = '3';
            app.Button_10 = uiradiobutton(app.VariationButtonGroup);
            app.Button_10.Text = '4';
            app.SierpiskiButton = uiradiobutton(app.VariationButtonGroup);
            app.SierpiskiButton.Text = ' Sierpiński';
            app.VicsekButton = uiradiobutton(app.VariationButtonGroup);
            app.VicsekButton.Text = 'Vicsek';
            app.BarnsleyFernTab = uitab(app.TabGroup);
            app.BarnsleyFernTab.Title = 'Barnsley Fern';
            app.AnimationCheckBox_3 = uicheckbox(app.BarnsleyFernTab);
            app.AnimationCheckBox_3.Text = 'Animation';
            app.SpeedButtonGroup_3 = uibuttongroup(app.BarnsleyFernTab);
            app.SpeedButtonGroup_3.Title = 'Speed';
            app.Button_11 = uiradiobutton(app.SpeedButtonGroup_3);
            app.Button_11.Text = '×10';
            app.Button_11.Value = true;
            app.Button_12 = uiradiobutton(app.SpeedButtonGroup_3);
            app.Button_12.Text = '×100';
            app.Button_13 = uiradiobutton(app.SpeedButtonGroup_3);
            app.Button_13.Text = '×1000';
            app.RunButton_3 = uibutton(app.BarnsleyFernTab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.ColorDropDown_3Label = uilabel(app.BarnsleyFernTab);
            app.ColorDropDown_3Label.Text = 'Color';
            app.ColorDropDown_3 = uidropdown(app.BarnsleyFernTab);
            app.ColorDropDown_3.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.ColorDropDown_3.Value = 'Red';
            app.NumberofIterationsEditField_3Label = uilabel(app.BarnsleyFernTab);
            app.NumberofIterationsEditField_3Label.Text = 'Number of Iterations';
            app.NumberofIterationsEditField_3 = uieditfield(app.BarnsleyFernTab, 'text');
            app.BackgroundColorDropDown_3Label = uilabel(app.BarnsleyFernTab);
            app.BackgroundColorDropDown_3Label.Text = 'Background Color';
            app.BackgroundColorDropDown_3 = uidropdown(app.BarnsleyFernTab);
            app.BackgroundColorDropDown_3.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown_3.Value = 'Dark';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux14

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end