classdef aux16 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BackgroundColorDropDown       matlab.ui.control.DropDown
        BackgroundColorDropDownLabel  matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        ColorDropDownLabel            matlab.ui.control.Label
        ymaxEditField                 matlab.ui.control.EditField
        ymaxEditFieldLabel            matlab.ui.control.Label
        xmaxEditField                 matlab.ui.control.EditField
        xmaxEditFieldLabel            matlab.ui.control.Label
        FieldWidthEditField           matlab.ui.control.EditField
        FieldWidthEditFieldLabel      matlab.ui.control.Label
        NumberofParticlesEditField    matlab.ui.control.EditField
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        ChoosecolorsbelowLabel        matlab.ui.control.Label
        BackgroundcolorblackbydefaultPixelscolorwhitebydefaultLabel  matlab.ui.control.Label
        VariationButtonGroup          matlab.ui.container.ButtonGroup
        PointsButton                  matlab.ui.control.RadioButton
        PixelsButton                  matlab.ui.control.RadioButton
        oddnumberLabel_2              matlab.ui.control.Label
        oddnumberLabel                matlab.ui.control.Label
        SpeedButtonGroup              matlab.ui.container.ButtonGroup
        Button_3                      matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        AnimationCheckBox             matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isequal(app.BackgroundColorDropDown.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        color=app.ColorDropDown.Value;
        particles=str2num(app.NumberofParticlesEditField.Value);
        field=str2num(app.FieldWidthEditField.Value);
        xmax=str2num(app.xmaxEditField.Value);
        ymax=str2num(app.ymaxEditField.Value);      
        animation=app.AnimationCheckBox.Value;
        if app.Button.Value
            n=10;
            if app.PixelsButton.Value
                browniantree(n,animation,field,particles,xmax,ymax);
            else
                browniantree1(n,animation,field,particles,xmax,ymax,bgcolor,color);
            end
        elseif app.Button_2.Value
            n=100;
            if app.PixelsButton.Value
                browniantree(n,animation,field,particles,xmax,ymax);
            else
                browniantree1(n,animation,field,particles,xmax,ymax,bgcolor,color);
            end
        elseif app.Button_3.Value
            n=1000;
            if app.PixelsButton.Value
                browniantree(n,animation,field,particles,xmax,ymax);
            else
                browniantree1(n,animation,field,particles,xmax,ymax,bgcolor,color);
            end
        else
            n=10;
            if app.PixelsButton.Value
                browniantree(n,animation,field,particles,xmax,ymax);
            else
                browniantree1(n,animation,field,particles,xmax,ymax,bgcolor,color);
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Brownian_tree_circle_large.png');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AnimationCheckBox = uicheckbox(app.UIFigure);
            app.AnimationCheckBox.Text = 'Animation';
            app.SpeedButtonGroup = uibuttongroup(app.UIFigure);
            app.SpeedButtonGroup.Title = 'Speed';
            app.Button = uiradiobutton(app.SpeedButtonGroup);
            app.Button.Text = '×10';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_2.Text = '×100';
            app.Button_3 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_3.Text = '×1000';
            app.oddnumberLabel = uilabel(app.UIFigure);
            app.oddnumberLabel.Text = '(odd number)';
            app.oddnumberLabel_2 = uilabel(app.UIFigure);
            app.oddnumberLabel_2.Text = '(odd number)';
            app.VariationButtonGroup = uibuttongroup(app.UIFigure);
            app.VariationButtonGroup.Title = 'Variation';
            app.PixelsButton = uiradiobutton(app.VariationButtonGroup);
            app.PixelsButton.Text = 'Pixels';
            app.PixelsButton.Value = true;
            app.PointsButton = uiradiobutton(app.VariationButtonGroup);
            app.PointsButton.Text = 'Points';
            app.BackgroundcolorblackbydefaultPixelscolorwhitebydefaultLabel = uilabel(app.UIFigure);
            app.BackgroundcolorblackbydefaultPixelscolorwhitebydefaultLabel.Text = 'Background color = black (by default) , Pixels color = white (by default)';
            app.ChoosecolorsbelowLabel = uilabel(app.UIFigure);
            app.ChoosecolorsbelowLabel.Text = 'Choose colors below';
            app.NumberofParticlesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.UIFigure, 'text');
            app.FieldWidthEditFieldLabel = uilabel(app.UIFigure);
            app.FieldWidthEditFieldLabel.Text = 'Field Width';
            app.FieldWidthEditField = uieditfield(app.UIFigure, 'text');
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.UIFigure, 'text');
            app.ymaxEditFieldLabel = uilabel(app.UIFigure);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.UIFigure, 'text');
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.ColorDropDown.Value = 'Red';
            app.BackgroundColorDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.UIFigure);
            app.BackgroundColorDropDown.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown.Value = 'Dark';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux16

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end