classdef aux17 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LeavesColorDropDown           matlab.ui.control.DropDown
        LeavesColorDropDownLabel      matlab.ui.control.Label
        BackgroundColorDropDown       matlab.ui.control.DropDown
        BackgroundColorDropDownLabel  matlab.ui.control.Label
        NumberofIterationsEditField   matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        BranchesColorDropDown         matlab.ui.control.DropDown
        BranchesColorDropDownLabel    matlab.ui.control.Label
        SpeedButtonGroup              matlab.ui.container.ButtonGroup
        Button_3                      matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        AnimationCheckBox             matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isequal(app.BackgroundColorDropDown.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField.Value);
        color1=app.BranchesColorDropDown.Value;
        color2=app.LeavesColorDropDown.Value;
        animation=app.AnimationCheckBox.Value;
        if app.Button.Value
            n=10;
            pythagoreantree1(n,animation,bgcolor,color1,color2,i);
        elseif app.Button_2.Value
            n=100;
            pythagoreantree1(n,animation,bgcolor,color1,color2,i);
        elseif app.Button_3.Value
            n=1000;
            pythagoreantree1(n,animation,bgcolor,color1,color2,i);
        else
            n=10;
            pythagoreantree1(n,animation,bgcolor,color1,color2,i);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PythagorasEtching.jpg');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AnimationCheckBox = uicheckbox(app.UIFigure);
            app.AnimationCheckBox.Text = 'Animation';
            app.SpeedButtonGroup = uibuttongroup(app.UIFigure);
            app.SpeedButtonGroup.Title = 'Speed';
            app.Button = uiradiobutton(app.SpeedButtonGroup);
            app.Button.Text = '×10';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_2.Text = '×100';
            app.Button_3 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_3.Text = '×1000';
            app.BranchesColorDropDownLabel = uilabel(app.UIFigure);
            app.BranchesColorDropDownLabel.Text = 'Branches Color';
            app.BranchesColorDropDown = uidropdown(app.UIFigure);
            app.BranchesColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta', 'White', 'Black'};
            app.BranchesColorDropDown.Value = 'Red';
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.BackgroundColorDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.UIFigure);
            app.BackgroundColorDropDown.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown.Value = 'Dark';
            app.LeavesColorDropDownLabel = uilabel(app.UIFigure);
            app.LeavesColorDropDownLabel.Text = 'Leaves Color';
            app.LeavesColorDropDown = uidropdown(app.UIFigure);
            app.LeavesColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta', 'White', 'Black'};
            app.LeavesColorDropDown.Value = 'Red';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux17

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end