classdef aux2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FieldWidthEditField          matlab.ui.control.EditField
        FieldWidthEditFieldLabel     matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        NumberofIterationsEditField  matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        SpeedButtonGroup             matlab.ui.container.ButtonGroup
        Button_3                     matlab.ui.control.RadioButton
        Button_2                     matlab.ui.control.RadioButton
        Button                       matlab.ui.control.RadioButton
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            i=str2num(app.NumberofIterationsEditField.Value);
            N=str2num(app.FieldWidthEditField.Value);
            if app.Button.Value
                n=10;
                gameoflife(i,n,N);
            elseif app.Button_2.Value
                n=100;
                gameoflife(i,n,N);
            elseif app.Button_3.Value
                n=1000;
                gameoflife(i,n,N);
            else
                n=10;
                gameoflife(i,n,N);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Ad5IUU.png';
            app.SpeedButtonGroup = uibuttongroup(app.UIFigure);
            app.SpeedButtonGroup.Title = 'Speed';
            app.Button = uiradiobutton(app.SpeedButtonGroup);
            app.Button.Text = '×10';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_2.Text = '×100';
            app.Button_3 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_3.Text = '×1000';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Use Mouse:'; 'Left Click = Add Cell'; 'Right Click = Zoom In'; 'Middle Click (Wheel) = Zoom Out'; 'Spacebar = Delete Cell'};
            app.FieldWidthEditFieldLabel = uilabel(app.UIFigure);
            app.FieldWidthEditFieldLabel.Text = 'Field Width';
            app.FieldWidthEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end