classdef aux21 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ZrotationLabel                matlab.ui.control.Label
        YrotationLabel                matlab.ui.control.Label
        XrotationLabel                matlab.ui.control.Label
        ThetaEditField                matlab.ui.control.EditField
        ThetaEditFieldLabel           matlab.ui.control.Label
        BetaEditField                 matlab.ui.control.EditField
        BetaEditFieldLabel            matlab.ui.control.Label
        REditField                    matlab.ui.control.EditField
        REditFieldLabel               matlab.ui.control.Label
        PhiEditField_2                matlab.ui.control.EditField
        PhiEditField_2Label           matlab.ui.control.Label
        LeavesColorDropDown           matlab.ui.control.DropDown
        LeavesColorDropDownLabel      matlab.ui.control.Label
        BackgroundColorDropDown       matlab.ui.control.DropDown
        BackgroundColorDropDownLabel  matlab.ui.control.Label
        NumberofLayersEditField       matlab.ui.control.EditField
        NumberofLayersEditFieldLabel  matlab.ui.control.Label
        BranchesColorDropDown         matlab.ui.control.DropDown
        BranchesColorDropDownLabel    matlab.ui.control.Label
        R1Label                       matlab.ui.control.Label
        SpeedButtonGroup              matlab.ui.container.ButtonGroup
        Button_3                      matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        AnimationCheckBox             matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isequal(app.BackgroundColorDropDown.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofLayersEditField.Value);
        theta=str2num(app.ThetaEditField.Value);
        phi=str2num(app.PhiEditField_2.Value);
        beta=str2num(app.BetaEditField.Value);
        r=str2num(app.REditField.Value);
        color1=app.BranchesColorDropDown.Value;
        color2=app.LeavesColorDropDown.Value;
        animation=app.AnimationCheckBox.Value;
        if app.Button.Value
            n=10;
            test3d(n,animation,bgcolor,color1,color2,r,theta,phi,beta,i);
        elseif app.Button_2.Value
            n=100;
            test3d(n,animation,bgcolor,color1,color2,r,theta,phi,beta,i);
        elseif app.Button_3.Value
            n=1000;
            test3d(n,animation,bgcolor,color1,color2,r,theta,phi,beta,i);
        else
            n=10;
            test3d(n,animation,bgcolor,color1,color2,r,theta,phi,beta,i);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Fractal_tree_(Plate_b_-_2) (1).jpg';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AnimationCheckBox = uicheckbox(app.UIFigure);
            app.AnimationCheckBox.Text = 'Animation';
            app.SpeedButtonGroup = uibuttongroup(app.UIFigure);
            app.SpeedButtonGroup.Title = 'Speed';
            app.Button = uiradiobutton(app.SpeedButtonGroup);
            app.Button.Text = '×10';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_2.Text = '×100';
            app.Button_3 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_3.Text = '×1000';
            app.R1Label = uilabel(app.UIFigure);
            app.R1Label.Text = '(R<1)';
            app.BranchesColorDropDownLabel = uilabel(app.UIFigure);
            app.BranchesColorDropDownLabel.Text = 'Branches Color';
            app.BranchesColorDropDown = uidropdown(app.UIFigure);
            app.BranchesColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta', 'White', 'Black'};
            app.BranchesColorDropDown.Value = 'Red';
            app.NumberofLayersEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofLayersEditFieldLabel.Text = 'Number of Layers';
            app.NumberofLayersEditField = uieditfield(app.UIFigure, 'text');
            app.BackgroundColorDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.UIFigure);
            app.BackgroundColorDropDown.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown.Value = 'Dark';
            app.LeavesColorDropDownLabel = uilabel(app.UIFigure);
            app.LeavesColorDropDownLabel.Text = 'Leaves Color';
            app.LeavesColorDropDown = uidropdown(app.UIFigure);
            app.LeavesColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta', 'White', 'Black'};
            app.LeavesColorDropDown.Value = 'Red';
            app.PhiEditField_2Label = uilabel(app.UIFigure);
            app.PhiEditField_2Label.Text = 'Phi';
            app.PhiEditField_2 = uieditfield(app.UIFigure, 'text');
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'text');
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'text');
            app.ThetaEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.UIFigure, 'text');
            app.XrotationLabel = uilabel(app.UIFigure);
            app.XrotationLabel.Text = 'X rotation';
            app.YrotationLabel = uilabel(app.UIFigure);
            app.YrotationLabel.Text = 'Y rotation';
            app.ZrotationLabel = uilabel(app.UIFigure);
            app.ZrotationLabel.Text = 'Z rotation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux21

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end