classdef aux5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        KochCurveTab                    matlab.ui.container.Tab
        SpeedButtonGroup_2              matlab.ui.container.ButtonGroup
        Button_6                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        AnimationCheckBox_2             matlab.ui.control.CheckBox
        CesroCurveCheckBox              matlab.ui.control.CheckBox
        BackgroundColorDropDown         matlab.ui.control.DropDown
        BackgroundColorDropDownLabel    matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        KochSnowflakeTab                matlab.ui.container.Tab
        SpeedButtonGroup                matlab.ui.container.ButtonGroup
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        AnimationCheckBox               matlab.ui.control.CheckBox
        AntiSnowflakeCheckBox           matlab.ui.control.CheckBox
        RightColorDropDown              matlab.ui.control.DropDown
        RightColorDropDownLabel         matlab.ui.control.Label
        LeftColorDropDown               matlab.ui.control.DropDown
        LeftColorDropDownLabel          matlab.ui.control.Label
        BackgroundColorDropDown_2       matlab.ui.control.DropDown
        BackgroundColorDropDown_2Label  matlab.ui.control.Label
        NumberofIterationsEditField_2   matlab.ui.control.EditField
        NumberofIterationsEditField_2Label  matlab.ui.control.Label
        TopBottomColorDropDown          matlab.ui.control.DropDown
        TopBottomColorDropDownLabel     matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isequal(app.BackgroundColorDropDown.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField.Value);
        color=app.ColorDropDown.Value;
        animation=app.AnimationCheckBox_2.Value;
        if app.Button_4.Value
            n=10;
            if app.CesroCurveCheckBox.Value
                kochcurve4(n,animation,bgcolor,color,i);
            else
                kochcurve1(n,animation,bgcolor,color,i);
            end
        elseif app.Button_5.Value
            n=100;
            if app.CesroCurveCheckBox.Value
                kochcurve4(n,animation,bgcolor,color,i);
            else
                kochcurve1(n,animation,bgcolor,color,i);
            end
        elseif app.Button_6.Value
            n=1000;
            if app.CesroCurveCheckBox.Value
                kochcurve4(n,animation,bgcolor,color,i);
            else
                kochcurve1(n,animation,bgcolor,color,i);
            end
        else
            n=10;
            if app.CesroCurveCheckBox.Value
                kochcurve4(n,animation,bgcolor,color,i);
            else
                kochcurve1(n,animation,bgcolor,color,i);
            end
        end
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
        if isequal(app.BackgroundColorDropDown_2.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField_2.Value);
        color1=app.TopBottomColorDropDown.Value;
        color2=app.LeftColorDropDown.Value;
        color3=app.RightColorDropDown.Value;
        animation=app.AnimationCheckBox.Value;
        if app.Button.Value
            n=10;
            if app.AntiSnowflakeCheckBox.Value
                kochcurve3(n,animation,bgcolor,color1,color2,color3,i);
            else
                kochcurve2(n,animation,bgcolor,color1,color2,color3,i);
            end
        elseif app.Button_2.Value
            n=100;
            if app.AyntiSnowflakeCheckBox.Value
                kochcurve3(n,animation,bgcolor,color1,color2,color3,i);
            else
                kochcurve2(n,animation,bgcolor,color1,color2,color3,i);
            end
        elseif app.Button_3.Value
            n=1000;
            if app.AntiSnowflakeCheckBox.Value
                kochcurve3(n,animation,bgcolor,color1,color2,color3,i);
            else
                kochcurve2(n,animation,bgcolor,color1,color2,color3,i);
            end
        else
            n=10;
            if app.AntiSnowflakeCheckBox.Value
                kochcurve3(n,animation,bgcolor,color1,color2,color3,i);
            else
                kochcurve2(n,animation,bgcolor,color1,color2,color3,i);
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'snowflake_iterate_005.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KochCurveTab = uitab(app.TabGroup);
            app.KochCurveTab.Title = 'Koch Curve';
            app.ColorDropDownLabel = uilabel(app.KochCurveTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.KochCurveTab);
            app.ColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.ColorDropDown.Value = 'Red';
            app.RunButton = uibutton(app.KochCurveTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NumberofIterationsEditFieldLabel = uilabel(app.KochCurveTab);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.KochCurveTab, 'text');
            app.BackgroundColorDropDownLabel = uilabel(app.KochCurveTab);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.KochCurveTab);
            app.BackgroundColorDropDown.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown.Value = 'Dark';
            app.CesroCurveCheckBox = uicheckbox(app.KochCurveTab);
            app.CesroCurveCheckBox.Text = 'Cesàro Curve';
            app.AnimationCheckBox_2 = uicheckbox(app.KochCurveTab);
            app.AnimationCheckBox_2.Text = 'Animation';
            app.SpeedButtonGroup_2 = uibuttongroup(app.KochCurveTab);
            app.SpeedButtonGroup_2.Title = 'Speed';
            app.Button_4 = uiradiobutton(app.SpeedButtonGroup_2);
            app.Button_4.Text = '×10';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.SpeedButtonGroup_2);
            app.Button_5.Text = '×100';
            app.Button_6 = uiradiobutton(app.SpeedButtonGroup_2);
            app.Button_6.Text = '×1000';
            app.KochSnowflakeTab = uitab(app.TabGroup);
            app.KochSnowflakeTab.Title = 'Koch Snowflake';
            app.RunButton_2 = uibutton(app.KochSnowflakeTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.TopBottomColorDropDownLabel = uilabel(app.KochSnowflakeTab);
            app.TopBottomColorDropDownLabel.Text = 'Top/Bottom Color';
            app.TopBottomColorDropDown = uidropdown(app.KochSnowflakeTab);
            app.TopBottomColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.TopBottomColorDropDown.Value = 'Red';
            app.NumberofIterationsEditField_2Label = uilabel(app.KochSnowflakeTab);
            app.NumberofIterationsEditField_2Label.Text = 'Number of Iterations';
            app.NumberofIterationsEditField_2 = uieditfield(app.KochSnowflakeTab, 'text');
            app.BackgroundColorDropDown_2Label = uilabel(app.KochSnowflakeTab);
            app.BackgroundColorDropDown_2Label.Text = 'Background Color';
            app.BackgroundColorDropDown_2 = uidropdown(app.KochSnowflakeTab);
            app.BackgroundColorDropDown_2.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown_2.Value = 'Dark';
            app.LeftColorDropDownLabel = uilabel(app.KochSnowflakeTab);
            app.LeftColorDropDownLabel.Text = 'Left Color';
            app.LeftColorDropDown = uidropdown(app.KochSnowflakeTab);
            app.LeftColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.LeftColorDropDown.Value = 'Red';
            app.RightColorDropDownLabel = uilabel(app.KochSnowflakeTab);
            app.RightColorDropDownLabel.Text = 'Right Color';
            app.RightColorDropDown = uidropdown(app.KochSnowflakeTab);
            app.RightColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.RightColorDropDown.Value = 'Red';
            app.AntiSnowflakeCheckBox = uicheckbox(app.KochSnowflakeTab);
            app.AntiSnowflakeCheckBox.Text = 'Anti-Snowflake';
            app.AnimationCheckBox = uicheckbox(app.KochSnowflakeTab);
            app.AnimationCheckBox.Text = 'Animation';
            app.SpeedButtonGroup = uibuttongroup(app.KochSnowflakeTab);
            app.SpeedButtonGroup.Title = 'Speed';
            app.Button = uiradiobutton(app.SpeedButtonGroup);
            app.Button.Text = '×10';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_2.Text = '×100';
            app.Button_3 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_3.Text = '×1000';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end