classdef aux6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LvyCurveTab                     matlab.ui.container.Tab
        SpeedButtonGroup                matlab.ui.container.ButtonGroup
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        AnimationCheckBox               matlab.ui.control.CheckBox
        BackgroundColorDropDown         matlab.ui.control.DropDown
        BackgroundColorDropDownLabel    matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        LvyTapestryTab                  matlab.ui.container.Tab
        BackgroundColorDropDown_2       matlab.ui.control.DropDown
        BackgroundColorDropDown_2Label  matlab.ui.control.Label
        RightColorDropDown              matlab.ui.control.DropDown
        RightColorDropDownLabel         matlab.ui.control.Label
        LeftColorDropDown               matlab.ui.control.DropDown
        LeftColorDropDownLabel          matlab.ui.control.Label
        BottomColorDropDown             matlab.ui.control.DropDown
        BottomColorDropDownLabel        matlab.ui.control.Label
        TopColorDropDown                matlab.ui.control.DropDown
        TopColorDropDownLabel           matlab.ui.control.Label
        VariationButtonGroup            matlab.ui.container.ButtonGroup
        InsideButton                    matlab.ui.control.RadioButton
        OutsideButton                   matlab.ui.control.RadioButton
        NumberofIterationsEditField_2   matlab.ui.control.EditField
        NumberofIterationsEditField_2Label  matlab.ui.control.Label
        SpeedButtonGroup_2              matlab.ui.container.ButtonGroup
        Button_6                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        AnimationCheckBox_2             matlab.ui.control.CheckBox
        RunButton_2                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isequal(app.BackgroundColorDropDown.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField.Value);
        color=app.ColorDropDown.Value;
        animation=app.AnimationCheckBox.Value;
        if app.Button.Value
            n=10;
            levy1(n,animation,bgcolor,color,i);
        elseif app.Button_2.Value
            n=100;
            levy1(n,animation,bgcolor,color,i);
        elseif app.Button_3.Value
            n=1000;
            levy1(n,animation,bgcolor,color,i);
        else
            n=10;
            levy1(n,animation,bgcolor,color,i);
        end
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
        if isequal(app.BackgroundColorDropDown_2.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField_2.Value);
        color1=app.TopColorDropDown.Value;
        color2=app.BottomColorDropDown.Value;      
        color3=app.LeftColorDropDown.Value;
        color4=app.RightColorDropDown.Value;
        animation=app.AnimationCheckBox_2.Value;
        if app.Button_4.Value
            n=10;
            if app.OutsideButton.Value
                levy2(n,animation,bgcolor,color1,color2,color3,color4,i);
            else
                levy3(n,animation,bgcolor,color1,color2,color3,color4,i);
            end
        elseif app.Button_5.Value
            n=100;
            if app.OutsideButton.Value
                levy2(n,animation,bgcolor,color1,color2,color3,color4,i);
            else
                levy3(n,animation,bgcolor,color1,color2,color3,color4,i);
            end
        elseif app.Button_6.Value
            n=1000;
            if app.OutsideButton.Value
                levy2(n,animation,bgcolor,color1,color2,color3,color4,i);
            else
                levy3(n,animation,bgcolor,color1,color2,color3,color4,i);
            end
        else
            n=10;
            if app.OutsideButton.Value
                levy2(n,animation,bgcolor,color1,color2,color3,color4,i);
            else
                levy3(n,animation,bgcolor,color1,color2,color3,color4,i);
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '14555156379_5260a209f2_k.jpg');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LvyCurveTab = uitab(app.TabGroup);
            app.LvyCurveTab.Title = 'Lévy Curve';
            app.RunButton = uibutton(app.LvyCurveTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ColorDropDownLabel = uilabel(app.LvyCurveTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LvyCurveTab);
            app.ColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.ColorDropDown.Value = 'Red';
            app.NumberofIterationsEditFieldLabel = uilabel(app.LvyCurveTab);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.LvyCurveTab, 'text');
            app.BackgroundColorDropDownLabel = uilabel(app.LvyCurveTab);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.LvyCurveTab);
            app.BackgroundColorDropDown.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown.Value = 'Dark';
            app.AnimationCheckBox = uicheckbox(app.LvyCurveTab);
            app.AnimationCheckBox.Text = 'Animation';
            app.SpeedButtonGroup = uibuttongroup(app.LvyCurveTab);
            app.SpeedButtonGroup.Title = 'Speed';
            app.Button = uiradiobutton(app.SpeedButtonGroup);
            app.Button.Text = '×10';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_2.Text = '×100';
            app.Button_3 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_3.Text = '×1000';
            app.LvyTapestryTab = uitab(app.TabGroup);
            app.LvyTapestryTab.Title = 'Lévy Tapestry';
            app.RunButton_2 = uibutton(app.LvyTapestryTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.AnimationCheckBox_2 = uicheckbox(app.LvyTapestryTab);
            app.AnimationCheckBox_2.Text = 'Animation';
            app.SpeedButtonGroup_2 = uibuttongroup(app.LvyTapestryTab);
            app.SpeedButtonGroup_2.Title = 'Speed';
            app.Button_4 = uiradiobutton(app.SpeedButtonGroup_2);
            app.Button_4.Text = '×10';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.SpeedButtonGroup_2);
            app.Button_5.Text = '×100';
            app.Button_6 = uiradiobutton(app.SpeedButtonGroup_2);
            app.Button_6.Text = '×1000';
            app.NumberofIterationsEditField_2Label = uilabel(app.LvyTapestryTab);
            app.NumberofIterationsEditField_2Label.Text = 'Number of Iterations';
            app.NumberofIterationsEditField_2 = uieditfield(app.LvyTapestryTab, 'text');
            app.VariationButtonGroup = uibuttongroup(app.LvyTapestryTab);
            app.VariationButtonGroup.Title = 'Variation';
            app.OutsideButton = uiradiobutton(app.VariationButtonGroup);
            app.OutsideButton.Text = 'Outside';
            app.OutsideButton.Value = true;
            app.InsideButton = uiradiobutton(app.VariationButtonGroup);
            app.InsideButton.Text = 'Inside';
            app.TopColorDropDownLabel = uilabel(app.LvyTapestryTab);
            app.TopColorDropDownLabel.Text = 'Top Color';
            app.TopColorDropDown = uidropdown(app.LvyTapestryTab);
            app.TopColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.TopColorDropDown.Value = 'Red';
            app.BottomColorDropDownLabel = uilabel(app.LvyTapestryTab);
            app.BottomColorDropDownLabel.Text = 'Bottom Color';
            app.BottomColorDropDown = uidropdown(app.LvyTapestryTab);
            app.BottomColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.BottomColorDropDown.Value = 'Red';
            app.LeftColorDropDownLabel = uilabel(app.LvyTapestryTab);
            app.LeftColorDropDownLabel.Text = 'Left Color';
            app.LeftColorDropDown = uidropdown(app.LvyTapestryTab);
            app.LeftColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.LeftColorDropDown.Value = 'Red';
            app.RightColorDropDownLabel = uilabel(app.LvyTapestryTab);
            app.RightColorDropDownLabel.Text = 'Right Color';
            app.RightColorDropDown = uidropdown(app.LvyTapestryTab);
            app.RightColorDropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.RightColorDropDown.Value = 'Red';
            app.BackgroundColorDropDown_2Label = uilabel(app.LvyTapestryTab);
            app.BackgroundColorDropDown_2Label.Text = 'Background Color';
            app.BackgroundColorDropDown_2 = uidropdown(app.LvyTapestryTab);
            app.BackgroundColorDropDown_2.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown_2.Value = 'Dark';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end