classdef aux7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Color2DropDown                matlab.ui.control.DropDown
        Color2DropDownLabel           matlab.ui.control.Label
        BackgroundColorDropDown       matlab.ui.control.DropDown
        BackgroundColorDropDownLabel  matlab.ui.control.Label
        NumberofIterationsEditField   matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        Color1DropDown                matlab.ui.control.DropDown
        Color1DropDownLabel           matlab.ui.control.Label
        SpeedButtonGroup              matlab.ui.container.ButtonGroup
        Button_4                      matlab.ui.control.RadioButton
        Button_3                      matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        AnimationCheckBox             matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        if isequal(app.BackgroundColorDropDown.Value,'Light')
            bgcolor='White';
        else
            bgcolor='Black';
        end
        i=str2num(app.NumberofIterationsEditField.Value);
        color1=app.Color1DropDown.Value;
        color2=app.Color2DropDown.Value;
        animation=app.AnimationCheckBox.Value;
        if app.Button_2.Value
            n=10;
            dragon1(n,animation,bgcolor,color1,color2,i);
        elseif app.Button_3.Value
            n=100;
            dragon1(n,animation,bgcolor,color1,color2,i);
        elseif app.Button_4.Value
            n=1000;
            dragon1(n,animation,bgcolor,color1,color2,i);
        else
            n=10;
            dragon1(n,animation,bgcolor,color1,color2,i);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'peakpx (1).jpg';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AnimationCheckBox = uicheckbox(app.UIFigure);
            app.AnimationCheckBox.Text = 'Animation';
            app.SpeedButtonGroup = uibuttongroup(app.UIFigure);
            app.SpeedButtonGroup.Title = 'Speed';
            app.Button_2 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_2.Text = '×10';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_3.Text = '×100';
            app.Button_4 = uiradiobutton(app.SpeedButtonGroup);
            app.Button_4.Text = '×1000';
            app.Color1DropDownLabel = uilabel(app.UIFigure);
            app.Color1DropDownLabel.Text = 'Color 1';
            app.Color1DropDown = uidropdown(app.UIFigure);
            app.Color1DropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.Color1DropDown.Value = 'Red';
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.BackgroundColorDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.BackgroundColorDropDown = uidropdown(app.UIFigure);
            app.BackgroundColorDropDown.Items = {'Light', 'Dark'};
            app.BackgroundColorDropDown.Value = 'Dark';
            app.Color2DropDownLabel = uilabel(app.UIFigure);
            app.Color2DropDownLabel.Text = 'Color 2';
            app.Color2DropDown = uidropdown(app.UIFigure);
            app.Color2DropDown.Items = {'Red', 'Green', 'Blue', 'Yellow', 'Magenta'};
            app.Color2DropDown.Value = 'Red';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end