classdef aux9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        etaEditField                 matlab.ui.control.EditField
        etaEditFieldLabel            matlab.ui.control.Label
        zetaEditField                matlab.ui.control.EditField
        zetaEditFieldLabel           matlab.ui.control.Label
        epsilonEditField             matlab.ui.control.EditField
        epsilonEditFieldLabel        matlab.ui.control.Label
        deltaEditField               matlab.ui.control.EditField
        deltaEditFieldLabel          matlab.ui.control.Label
        betaEditField                matlab.ui.control.EditField
        betaEditFieldLabel           matlab.ui.control.Label
        alphaEditField               matlab.ui.control.EditField
        alphaEditFieldLabel          matlab.ui.control.Label
        NumberofIterationsEditField  matlab.ui.control.EditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            alpha=str2num(app.alphaEditField.Value);
            beta=str2num(app.betaEditField.Value);
            delta=str2num(app.deltaEditField.Value);
            epsilon=str2num(app.epsilonEditField.Value);
            zeta=str2num(app.zetaEditField.Value);
            eta=str2num(app.etaEditField.Value);
            i=str2num(app.NumberofIterationsEditField.Value);
            deRham(alpha,beta,delta,epsilon,zeta,eta,i);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image.png');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'text');
            app.betaEditFieldLabel = uilabel(app.UIFigure);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.UIFigure, 'text');
            app.deltaEditFieldLabel = uilabel(app.UIFigure);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.UIFigure, 'text');
            app.epsilonEditFieldLabel = uilabel(app.UIFigure);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.UIFigure, 'text');
            app.zetaEditFieldLabel = uilabel(app.UIFigure);
            app.zetaEditFieldLabel.Text = 'zeta';
            app.zetaEditField = uieditfield(app.UIFigure, 'text');
            app.etaEditFieldLabel = uilabel(app.UIFigure);
            app.etaEditFieldLabel.Text = 'eta';
            app.etaEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aux9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end